classdef Live_Video_Analysis_Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LiveVideoCameraConfigurationTab  matlab.ui.container.Tab
        FPSLabel                        matlab.ui.control.Label
        DelayBetweenEachFramesmsDropDown  matlab.ui.control.DropDown
        DelayBetweenEachFramesmsDropDownLabel  matlab.ui.control.Label
        WidthxHeightLabel               matlab.ui.control.Label
        CaptureButton                   matlab.ui.control.Button
        ImageSizeLabel                  matlab.ui.control.Label
        CameraStatusLamp                matlab.ui.control.Lamp
        CameraStatusLampLabel           matlab.ui.control.Label
        YDistanceSpinner                matlab.ui.control.Spinner
        YDistanceSpinnerLabel           matlab.ui.control.Label
        XDistanceSpinner                matlab.ui.control.Spinner
        XDistanceSpinnerLabel           matlab.ui.control.Label
        MadebyVenisPrajapatiLabel       matlab.ui.control.Label
        SizeSpinner                     matlab.ui.control.Spinner
        SizeSpinnerLabel                matlab.ui.control.Label
        LiveCameraLabel                 matlab.ui.control.Label
        StartCameraButton               matlab.ui.control.StateButton
        LiveCameraSwitch                matlab.ui.control.ToggleSwitch
        VideoResolutionDropDown         matlab.ui.control.DropDown
        VideoResolutionDropDownLabel    matlab.ui.control.Label
        CameraInputDropDown             matlab.ui.control.DropDown
        CameraInputDropDownLabel        matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        RGBChannelAnalysisTab           matlab.ui.container.Tab
        MadebyVenisPrajapatiLabel_2     matlab.ui.control.Label
        GenerateRGBChannelDataButton    matlab.ui.control.Button
        UIAxes_RI                       matlab.ui.control.UIAxes
        UIAxes_GI                       matlab.ui.control.UIAxes
        UIAxes_BI                       matlab.ui.control.UIAxes
        UIAxes_RH                       matlab.ui.control.UIAxes
        UIAxes_GH                       matlab.ui.control.UIAxes
        UIAxes_BH                       matlab.ui.control.UIAxes
        ApplyingThresholdTab            matlab.ui.container.Tab
        Label_4                         matlab.ui.control.Label
        TotalnumberofPixelsintheImageLabel  matlab.ui.control.Label
        RGBLabel                        matlab.ui.control.Label
        AverageIntensityoftheHighlightedRegionLabel  matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        TotalnumberofPixelsinHighlightedRegionLabel  matlab.ui.control.Label
        GenerateAnalysisofROIButton     matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        BlueColorendingSpinner          matlab.ui.control.Spinner
        BlueColorendingSpinnerLabel     matlab.ui.control.Label
        GreenColorendingSpinner         matlab.ui.control.Spinner
        GreenColorendingSpinnerLabel    matlab.ui.control.Label
        BlueColorstartingSpinner        matlab.ui.control.Spinner
        BlueColorstartingSpinnerLabel   matlab.ui.control.Label
        GreenColorstartingSpinner       matlab.ui.control.Spinner
        GreenColorstartingSpinnerLabel  matlab.ui.control.Label
        RedColorendingSpinner           matlab.ui.control.Spinner
        RedColorendingSpinnerLabel      matlab.ui.control.Label
        RedColorstartingSpinner         matlab.ui.control.Spinner
        RedColorstartingSpinnerLabel    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        RetrieveImageforProcessingButton  matlab.ui.control.Button
        MadebyVenisPrajapatiLabel_3     matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        PixelAcquisitionTab             matlab.ui.container.Tab
        MadebyVenisPrajapatiLabel_4     matlab.ui.control.Label
        PixelAcquiringButton            matlab.ui.control.StateButton
        CapturingStatusSwitch           matlab.ui.control.Switch
        CapturingStatusSwitchLabel      matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        YAxisSpinner                    matlab.ui.control.Spinner
        YAxisSpinnerLabel               matlab.ui.control.Label
        XAxisSpinner                    matlab.ui.control.Spinner
        XAxisSpinnerLabel               matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        PixelIntensityAcquisitionTab    matlab.ui.container.Tab
        FPSLabel2                       matlab.ui.control.Label
        MadebyVenisPrajapatiLabel_5     matlab.ui.control.Label
        UIAxes4_3                       matlab.ui.control.UIAxes
        UIAxes4_2                       matlab.ui.control.UIAxes
        UIAxes4_1                       matlab.ui.control.UIAxes
        ExportLiveDataTab               matlab.ui.container.Tab
        MadebyVenisPrajapatiLabel_6     matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        DeveloperInformationTab         matlab.ui.container.Tab
        Hyperlink3                      matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        Hyperlink                       matlab.ui.control.Hyperlink
        VenisVPrajapatiLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = public)
        
        camList; % available camera list
        cameraInputName; % current input camera name
        Camera; % webcam variable e.g.-('USB2.0 PC CAMERA')
        resolutionList; % available resolution list
        videoResolution; % new resolution to apply to current video

        delay = 1; % delay between capturing 2 consecutive frames
        timeNow = 0; % time starting point for pixel intensity aquisition
        liveTimer; % timer to trigger pixel analysis at specific time interval
        
        capturedImg; % current captured images stored in the variable
        captureCurrentVideo; % records current video
        currentImg; % captured current image for cropping
        
        croppedImg; % cropped image after cropping
        sizeOfImg; % size of current video (row,col,channels)
        heightOfImg; % rows of current video
        widthOfImg; % columns of current video
        lengthOfSquareImg = 2; % length of side of Region Of Interest Square
        
        xDistanceOfImg = 1; % x-distance of Region Of Interest Square from the origin (upper left corner of live video)
        yDistanceOfImg = 1; % y-distance of Region Of Interest Square from the origin (upper left corner of live video)
        
        xAxis; % x-axis of image to aquire RGB value w.r.t time
        yAxis; % y-axis of image to aquire RGB value w.r.t time

        threshImg; % image to perform operations related to applying threshold values in a specific range
        pixelCount = 0; % number of highlighted pixels after applying threshold

        r1=0;
        r2=0;
        g1=0;
        g2=0;
        b1=0;
        b2=0;

        red=0;
        green=0;
        blue=0;

        table_index=1; % index to table data

    end

    methods (Access = public)

        function live_capture(app, obj, event)

            app.timeNow = app.timeNow + 1;

            liveImage = snapshot(app.Camera); % stored live image for pixel analysis
            pixels_RGB = liveImage(app.xDistanceOfImg + app.xAxis - 1, app.yDistanceOfImg + app.yAxis - 1, :);

            plot(app.UIAxes4_1, app.timeNow, pixels_RGB(1), 'Color', '#FF0000', 'Marker','.');
            plot(app.UIAxes4_2, app.timeNow, pixels_RGB(2), 'Color', '#00FF00', 'Marker','.');
            plot(app.UIAxes4_3, app.timeNow, pixels_RGB(3), 'Color', '#0000FF', 'Marker','.');

            app.UITable.Data(app.table_index, 1) = app.table_index;
            app.UITable.Data(app.table_index, 2) = app.timeNow;
            app.UITable.Data(app.table_index, 3) = pixels_RGB(1);
            app.UITable.Data(app.table_index, 4) = pixels_RGB(2);
            app.UITable.Data(app.table_index, 5) = pixels_RGB(3);

            app.table_index = app.table_index + 1;

        end
         
    end
    
    methods (Access = private)
        
        function apply_threshold(app)

            app.threshImg = app.croppedImg;
            app.pixelCount = 0;

            app.red = 0;
            app.green = 0;
            app.blue = 0;

            for i = 1 : app.lengthOfSquareImg
                for j = 1 : app.lengthOfSquareImg

                    if (app.threshImg(i,j,1) >= app.r1 && app.threshImg(i,j,1) <= app.r2) && (app.threshImg(i,j,2) >= app.g1 && app.threshImg(i,j,2) <= app.g2) && (app.threshImg(i,j,3) >= app.b1 && app.threshImg(i,j,3) <= app.b2)

                        app.pixelCount = app.pixelCount + 1;

                        app.red = app.red + double(app.threshImg(i,j,1));
                        app.green = app.green + double(app.threshImg(i,j,2));
                        app.blue = app.blue + double(app.threshImg(i,j,3));

                        app.threshImg(i,j,1) = 204;
                        app.threshImg(i,j,2) = 64;
                        app.threshImg(i,j,3) = 204;

                    end

                end
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            evalin("base", 'clear cam');

            app.UIAxes4_1.XLim = [0 5001];
            app.UIAxes4_2.XLim = [0 5001];
            app.UIAxes4_3.XLim = [0 5001];

            app.UIAxes4_1.YLim = [0 255];
            app.UIAxes4_2.YLim = [0 255];
            app.UIAxes4_3.YLim = [0 255];

            hold(app.UIAxes4_1,'on');
            hold(app.UIAxes4_2,'on');
            hold(app.UIAxes4_3,'on');

            app.RedColorstartingSpinner.Limits = [0 255];
            app.RedColorendingSpinner.Limits = [0 255];
            app.GreenColorstartingSpinner.Limits = [0 255];
            app.GreenColorendingSpinner.Limits = [0 255];
            app.BlueColorstartingSpinner.Limits = [0 255];
            app.BlueColorendingSpinner.Limits = [0 255];

            app.StartCameraButton.Enable = "off";
            app.VideoResolutionDropDown.Enable = "off";
            app.LiveCameraSwitch.Enable = "off";
            app.DelayBetweenEachFramesmsDropDown.Enable = "off";

            app.SizeSpinner.Enable = "off";
            app.XDistanceSpinner.Enable = "off";
            app.YDistanceSpinner.Enable = "off";
            app.CaptureButton.Enable = "off";
            
            app.GenerateRGBChannelDataButton.Enable = "off";
            app.PixelAcquiringButton.Enable = "off";

            app.XAxisSpinner.Enable = "off";
            app.YAxisSpinner.Enable = "off";
            app.CapturingStatusSwitch.Enable = "off";

            app.RetrieveImageforProcessingButton.Enable = "off";
            app.GenerateAnalysisofROIButton.Enable = "off";

            app.RedColorstartingSpinner.Enable = "off";
            app.RedColorendingSpinner.Enable = "off";
            app.GreenColorstartingSpinner.Enable = "off";
            app.GreenColorendingSpinner.Enable = "off";
            app.BlueColorstartingSpinner.Enable = "off";
            app.BlueColorendingSpinner.Enable = "off";

            app.Lamp.Color = 'r';

        end

        % Drop down opening function: CameraInputDropDown
        function CameraInputDropDownOpening(app, event)
            
            app.camList = webcamlist;
            app.CameraInputDropDown.Items = app.camList;

            app.StartCameraButton.Enable = "on";

        end

        % Value changed function: StartCameraButton
        function StartCameraButtonValueChanged(app, event)
            
            value = app.StartCameraButton.Value;
            
            if value == true

                app.StartCameraButton.Text = 'Stop Camera';

                app.CameraInputDropDown.Enable = "off";
                app.VideoResolutionDropDown.Enable = "on";
                app.DelayBetweenEachFramesmsDropDown.Enable = "on";
                app.LiveCameraSwitch.Enable = "on";

                app.FPSLabel.Text = string(1/app.delay) + " FPS";
                app.FPSLabel2.Text = string(1/app.delay) + " FPS";

                % Camera Operating Code
                app.cameraInputName = app.CameraInputDropDown.Value;
            
                app.Camera = webcam(app.cameraInputName);
                app.resolutionList = app.Camera.AvailableResolutions;
                app.VideoResolutionDropDown.Items = app.resolutionList;

                app.videoResolution = app.VideoResolutionDropDown.Value;
                app.Camera.Resolution = app.videoResolution;
                
                y = split(app.videoResolution,'x');
                app.widthOfImg = str2double(y{1});
                app.heightOfImg = str2double(y{2});

                app.lengthOfSquareImg = min([app.widthOfImg, app.heightOfImg]) - 1;
                app.SizeSpinner.Limits = [2 app.lengthOfSquareImg];
                app.SizeSpinner.Value = app.lengthOfSquareImg;

                app.XDistanceSpinner.Limits = [1 app.widthOfImg - (app.lengthOfSquareImg - 1)];
                app.YDistanceSpinner.Limits = [1 app.heightOfImg - (app.lengthOfSquareImg - 1)];

                app.captureCurrentVideo = image(app.UIAxes,zeros(size(app.capturedImg),'uint8'));
                preview(app.Camera,app.captureCurrentVideo);

                app.CameraStatusLamp.Color = 'g';

            else

                app.StartCameraButton.Text = 'Start Camera';

                app.CameraInputDropDown.Enable = "on";
                app.VideoResolutionDropDown.Enable = "off";
                app.DelayBetweenEachFramesmsDropDown.Enable = "off";
                app.LiveCameraSwitch.Enable = "off";

                app.lengthOfSquareImg = 2;
                app.xDistanceOfImg = 1;
                app.yDistanceOfImg = 1;

                delete(app.Camera);
                app.CameraStatusLamp.Color = 'r';

            end
        end

        % Value changed function: VideoResolutionDropDown
        function VideoResolutionDropDownValueChanged(app, event)
            
            app.videoResolution = app.VideoResolutionDropDown.Value;
            app.Camera.Resolution = app.videoResolution;
            
            y = split(app.videoResolution,'x');
            app.widthOfImg = str2double(y{1});
            app.heightOfImg = str2double(y{2});

            app.xDistanceOfImg = 1;
            app.yDistanceOfImg = 1;

            app.lengthOfSquareImg = min([app.widthOfImg, app.heightOfImg]) - 1;
            app.SizeSpinner.Limits = [2 app.lengthOfSquareImg];
            app.SizeSpinner.Value = app.lengthOfSquareImg;

            app.XDistanceSpinner.Limits = [1 app.widthOfImg - (app.lengthOfSquareImg - 1)];
            app.YDistanceSpinner.Limits = [1 app.heightOfImg - (app.lengthOfSquareImg - 1)];

        end

        % Value changed function: DelayBetweenEachFramesmsDropDown
        function DelayBetweenEachFramesmsDropDownValueChanged(app, event)
            
            value = app.DelayBetweenEachFramesmsDropDown.Value;
            
            app.delay = str2double(value)*0.001;

            app.FPSLabel.Text = string(1000/str2double(value)) + " FPS";
            app.FPSLabel2.Text = string(1000/str2double(value)) + " FPS";

        end

        % Value changed function: LiveCameraSwitch
        function LiveCameraSwitchValueChanged(app, event)
            
            value = app.LiveCameraSwitch.Value;
            
            if strcmp(value, 'On')

                app.StartCameraButton.Enable = "off";
                app.VideoResolutionDropDown.Enable = "off";
                app.DelayBetweenEachFramesmsDropDown.Enable = "off";

                app.SizeSpinner.Enable = "on";
                app.XDistanceSpinner.Enable = "on";
                app.YDistanceSpinner.Enable = "on";
                app.CaptureButton.Enable = "on";

                app.currentImg = snapshot(app.Camera);
                
                app.croppedImg = imcrop(app.currentImg,[app.xDistanceOfImg app.yDistanceOfImg app.lengthOfSquareImg-1 app.lengthOfSquareImg-1]);
                imshow(app.croppedImg, 'Parent', app.UIAxes2);

                app.WidthxHeightLabel.Text = string(app.lengthOfSquareImg) + ' x ' + string(app.lengthOfSquareImg) + ' x 3';

            else

                app.StartCameraButton.Enable = "on";
                app.VideoResolutionDropDown.Enable = "on";
                app.DelayBetweenEachFramesmsDropDown.Enable = "on";

                app.SizeSpinner.Enable = "off";
                app.XDistanceSpinner.Enable = "off";
                app.YDistanceSpinner.Enable = "off";
                app.CaptureButton.Enable = "off";

            end

        end

        % Value changing function: SizeSpinner
        function SizeSpinnerValueChanging(app, event)
            
            app.lengthOfSquareImg = event.Value;
            app.XDistanceSpinner.Limits = [1 app.widthOfImg - (app.lengthOfSquareImg - 1)];
            app.YDistanceSpinner.Limits = [1 app.heightOfImg - (app.lengthOfSquareImg - 1)];

            app.croppedImg = imcrop(app.currentImg,[app.xDistanceOfImg app.yDistanceOfImg app.lengthOfSquareImg-1 app.lengthOfSquareImg-1]);
            imshow(app.croppedImg, 'Parent', app.UIAxes2);

            app.WidthxHeightLabel.Text = string(app.lengthOfSquareImg) + ' x ' + string(app.lengthOfSquareImg) + ' x 3';

        end

        % Value changing function: XDistanceSpinner
        function XDistanceSpinnerValueChanging(app, event)
            
            app.xDistanceOfImg = event.Value;
            app.SizeSpinner.Limits = [2 min([(app.widthOfImg - app.xDistanceOfImg), (app.heightOfImg - app.yDistanceOfImg)])];

            app.croppedImg = imcrop(app.currentImg,[app.xDistanceOfImg app.yDistanceOfImg app.lengthOfSquareImg-1 app.lengthOfSquareImg-1]);
            imshow(app.croppedImg, 'Parent', app.UIAxes2);

            app.WidthxHeightLabel.Text = string(app.lengthOfSquareImg) + ' x ' + string(app.lengthOfSquareImg) + ' x 3';

        end

        % Value changing function: YDistanceSpinner
        function YDistanceSpinnerValueChanging(app, event)
            
            app.yDistanceOfImg = event.Value;
            app.SizeSpinner.Limits = [2 min([(app.widthOfImg - app.xDistanceOfImg), (app.heightOfImg - app.yDistanceOfImg)])];

            app.croppedImg = imcrop(app.currentImg,[app.xDistanceOfImg app.yDistanceOfImg app.lengthOfSquareImg-1 app.lengthOfSquareImg-1]);
            imshow(app.croppedImg, 'Parent', app.UIAxes2);

            app.WidthxHeightLabel.Text = string(app.lengthOfSquareImg) + ' x ' + string(app.lengthOfSquareImg) + ' x 3';

        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            
            app.currentImg = snapshot(app.Camera);
            imshow(app.currentImg, 'Parent', app.UIAxes2);

            app.GenerateRGBChannelDataButton.Enable = "on";
            app.RetrieveImageforProcessingButton.Enable = "on";
            app.PixelAcquiringButton.Enable = "on";
            
            app.croppedImg = imcrop(app.currentImg,[app.xDistanceOfImg app.yDistanceOfImg app.lengthOfSquareImg-1 app.lengthOfSquareImg-1]);
            imshow(app.croppedImg, 'Parent', app.UIAxes2);

            app.WidthxHeightLabel.Text = string(app.lengthOfSquareImg) + ' x ' + string(app.lengthOfSquareImg) + ' x 3';

        end

        % Button pushed function: GenerateRGBChannelDataButton
        function GenerateRGBChannelDataButtonPushed(app, event)
            
            rImg = app.croppedImg(:,:,1);
            gImg = app.croppedImg(:,:,2);
            bImg = app.croppedImg(:,:,3);

            imshow(rImg, 'Parent', app.UIAxes_RI);
            imshow(gImg, 'Parent', app.UIAxes_GI);
            imshow(bImg, 'Parent', app.UIAxes_BI);

            histogram(app.UIAxes_RH, rImg);
            histogram(app.UIAxes_GH, gImg);
            histogram(app.UIAxes_BH, bImg);

        end

        % Button pushed function: RetrieveImageforProcessingButton
        function RetrieveImageforProcessingButtonPushed(app, event)

            app.GenerateAnalysisofROIButton.Enable = "on";

            app.RedColorstartingSpinner.Enable = "on";
            app.RedColorendingSpinner.Enable = "on";
            app.GreenColorstartingSpinner.Enable = "on";
            app.GreenColorendingSpinner.Enable = "on";
            app.BlueColorstartingSpinner.Enable = "on";
            app.BlueColorendingSpinner.Enable = "on";

            imshow(app.croppedImg, 'Parent', app.UIAxes4);

        end

        % Value changing function: RedColorstartingSpinner
        function RedColorstartingSpinnerValueChanging(app, event)
            
            app.r1 = event.Value;

        end

        % Value changing function: RedColorendingSpinner
        function RedColorendingSpinnerValueChanging(app, event)
            
            app.r2 = event.Value;
            
        end

        % Value changing function: GreenColorstartingSpinner
        function GreenColorstartingSpinnerValueChanging(app, event)
            
            app.g1= event.Value;
            
        end

        % Value changing function: GreenColorendingSpinner
        function GreenColorendingSpinnerValueChanging(app, event)
            
            app.g2 = event.Value;
            
        end

        % Value changing function: BlueColorstartingSpinner
        function BlueColorstartingSpinnerValueChanging(app, event)

            app.b1 = event.Value;
            
        end

        % Value changing function: BlueColorendingSpinner
        function BlueColorendingSpinnerValueChanging(app, event)
            
            app.b2 = event.Value;
            
        end

        % Value changed function: RedColorstartingSpinner
        function RedColorstartingSpinnerValueChanged(app, event)

            app.apply_threshold();
            imshow(app.threshImg, 'Parent', app.UIAxes4);

        end

        % Value changed function: RedColorendingSpinner
        function RedColorendingSpinnerValueChanged(app, event)
            
            app.apply_threshold();
            imshow(app.threshImg, 'Parent', app.UIAxes4);

        end

        % Value changed function: GreenColorstartingSpinner
        function GreenColorstartingSpinnerValueChanged(app, event)
            
            app.apply_threshold();
            imshow(app.threshImg, 'Parent', app.UIAxes4);

        end

        % Value changed function: GreenColorendingSpinner
        function GreenColorendingSpinnerValueChanged(app, event)
         
            app.apply_threshold();
            imshow(app.threshImg, 'Parent', app.UIAxes4);

        end

        % Value changed function: BlueColorstartingSpinner
        function BlueColorstartingSpinnerValueChanged(app, event)
           
            app.apply_threshold();
            imshow(app.threshImg, 'Parent', app.UIAxes4);

        end

        % Value changed function: BlueColorendingSpinner
        function BlueColorendingSpinnerValueChanged(app, event)
          
            app.apply_threshold();
            imshow(app.threshImg, 'Parent', app.UIAxes4);

        end

        % Button pushed function: GenerateAnalysisofROIButton
        function GenerateAnalysisofROIButtonPushed(app, event)
            
            total = app.lengthOfSquareImg^2;
            percent = round(app.pixelCount/total, 2)*100;

            app.Label_3.Text = string(total);

            if app.pixelCount == 0

                app.Label_4.Text = '0';
                app.RGBLabel.Text = '0';
            
            else

                r = round(app.red/app.pixelCount, 2);
                g = round(app.green/app.pixelCount, 2);
                b = round(app.blue/app.pixelCount, 2);


                app.Label_4.Text = string(app.pixelCount) + ' ( ' + string(percent) + '% )';
    
                app.RGBLabel.Text = '[' + string(r) + ' ' + string(g) + ' ' + string(b) + ']';

            end

        end

        % Value changed function: PixelAcquiringButton
        function PixelAcquiringButtonValueChanged(app, event)
            
            value = app.PixelAcquiringButton.Value;

            if value == true

                app.XAxisSpinner.Enable = "on";
                app.YAxisSpinner.Enable = "on";

                app.CapturingStatusSwitch.Enable = "on";

                imshow(app.croppedImg, 'Parent', app.UIAxes3);

                app.XAxisSpinner.Limits = [1 app.lengthOfSquareImg];
                app.YAxisSpinner.Limits = [1 app.lengthOfSquareImg];

                app.XAxisSpinner.Value = 1;
                app.YAxisSpinner.Value = 1;

                app.xAxis = 1;
                app.yAxis = 1;

                app.LiveCameraSwitch.Enable = "off";
                app.GenerateRGBChannelDataButton.Enable = "off";

                app.SizeSpinner.Enable = "off";
                app.XDistanceSpinner.Enable = "off";
                app.YDistanceSpinner.Enable = "off";
                app.CaptureButton.Enable = "off";

            else

                app.XAxisSpinner.Enable = "off";
                app.YAxisSpinner.Enable = "off";

                app.CapturingStatusSwitch.Enable = "off";

                app.LiveCameraSwitch.Enable = "on";
                app.GenerateRGBChannelDataButton.Enable = "on";

                app.SizeSpinner.Enable = "on";
                app.XDistanceSpinner.Enable = "on";
                app.YDistanceSpinner.Enable = "on";
                app.CaptureButton.Enable = "on";

            end
        end

        % Value changing function: XAxisSpinner
        function XAxisSpinnerValueChanging(app, event)
            
            app.xAxis = event.Value;
            pixelImg = app.croppedImg;
            
            pixelImg(app.xAxis,:,:) = 255;
            pixelImg(:,app.yAxis,:) = 255;
            pixelImg(app.xAxis,app.yAxis,:) = 0;
            
            imshow(pixelImg, 'Parent', app.UIAxes3);

        end

        % Value changing function: YAxisSpinner
        function YAxisSpinnerValueChanging(app, event)
            
            app.yAxis = event.Value;
            pixelImg = app.croppedImg;

            pixelImg(app.xAxis,:,:) = 255;
            pixelImg(:,app.yAxis,:) = 255;
            pixelImg(app.xAxis,app.yAxis,:) = 0;

            imshow(pixelImg, 'Parent', app.UIAxes3);

        end

        % Value changed function: CapturingStatusSwitch
        function CapturingStatusSwitchValueChanged(app, event)
            
            value = app.CapturingStatusSwitch.Value;
          
            if strcmp(value, 'On')

                app.timeNow = 0;

                app.Lamp.Color = 'g';

                app.PixelAcquiringButton.Enable = 'off';

                app.RetrieveImageforProcessingButton.Enable = "off";
                app.GenerateAnalysisofROIButton.Enable = "off";

                app.XAxisSpinner.Enable = "off";
                app.YAxisSpinner.Enable = "off";

                cla(app.UIAxes4_1);
                cla(app.UIAxes4_2);
                cla(app.UIAxes4_3);

                app.liveTimer = timer('Period', app.delay, 'BusyMode', 'drop', 'ExecutionMode', 'fixedDelay', 'TimerFcn', @app.live_capture);
                start(app.liveTimer);
                
            else

                stop(app.liveTimer);
                delete(app.liveTimer);

                app.Lamp.Color = 'r';

                app.PixelAcquiringButton.Enable = 'on';

                app.RetrieveImageforProcessingButton.Enable = "on";
                app.GenerateAnalysisofROIButton.Enable = "on";

                app.XAxisSpinner.Enable = "on";
                app.YAxisSpinner.Enable = "on";

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LiveVideoCameraConfigurationTab = uitab(app.TabGroup);
            app.LiveVideoCameraConfigurationTab.Title = 'Live Video & Camera Configuration';
            app.UIAxes = uiaxes(app.LiveVideoCameraConfigurationTab);
            title(app.UIAxes, 'Camera Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.LiveVideoCameraConfigurationTab);
            title(app.UIAxes2, 'Cropped Video')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CameraInputDropDownLabel = uilabel(app.LiveVideoCameraConfigurationTab);
            app.CameraInputDropDownLabel.Text = 'Camera Input';
            app.CameraInputDropDown = uidropdown(app.LiveVideoCameraConfigurationTab);
            app.CameraInputDropDown.Items = {'Camera'};
            app.CameraInputDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CameraInputDropDownOpening, true);
            app.CameraInputDropDown.Value = 'Camera';
            app.VideoResolutionDropDownLabel = uilabel(app.LiveVideoCameraConfigurationTab);
            app.VideoResolutionDropDownLabel.Text = 'Video Resolution';
            app.VideoResolutionDropDown = uidropdown(app.LiveVideoCameraConfigurationTab);
            app.VideoResolutionDropDown.Items = {'Width x Height'};
            app.VideoResolutionDropDown.ValueChangedFcn = createCallbackFcn(app, @VideoResolutionDropDownValueChanged, true);
            app.VideoResolutionDropDown.Value = 'Width x Height';
            app.LiveCameraSwitch = uiswitch(app.LiveVideoCameraConfigurationTab, 'toggle');
            app.LiveCameraSwitch.Items = {'On', 'Off'};
            app.LiveCameraSwitch.ValueChangedFcn = createCallbackFcn(app, @LiveCameraSwitchValueChanged, true);
            app.StartCameraButton = uibutton(app.LiveVideoCameraConfigurationTab, 'state');
            app.StartCameraButton.ValueChangedFcn = createCallbackFcn(app, @StartCameraButtonValueChanged, true);
            app.StartCameraButton.Text = 'Start Camera';
            app.LiveCameraLabel = uilabel(app.LiveVideoCameraConfigurationTab);
            app.LiveCameraLabel.Text = 'Live Camera';
            app.SizeSpinnerLabel = uilabel(app.LiveVideoCameraConfigurationTab);
            app.SizeSpinnerLabel.Text = 'Size';
            app.SizeSpinner = uispinner(app.LiveVideoCameraConfigurationTab);
            app.SizeSpinner.ValueChangingFcn = createCallbackFcn(app, @SizeSpinnerValueChanging, true);
            app.MadebyVenisPrajapatiLabel = uilabel(app.LiveVideoCameraConfigurationTab);
            app.MadebyVenisPrajapatiLabel.Text = 'Made by : Venis Prajapati';
            app.XDistanceSpinnerLabel = uilabel(app.LiveVideoCameraConfigurationTab);
            app.XDistanceSpinnerLabel.Text = 'X-Distance';
            app.XDistanceSpinner = uispinner(app.LiveVideoCameraConfigurationTab);
            app.XDistanceSpinner.ValueChangingFcn = createCallbackFcn(app, @XDistanceSpinnerValueChanging, true);
            app.YDistanceSpinnerLabel = uilabel(app.LiveVideoCameraConfigurationTab);
            app.YDistanceSpinnerLabel.Text = 'Y-Distance';
            app.YDistanceSpinner = uispinner(app.LiveVideoCameraConfigurationTab);
            app.YDistanceSpinner.ValueChangingFcn = createCallbackFcn(app, @YDistanceSpinnerValueChanging, true);
            app.CameraStatusLampLabel = uilabel(app.LiveVideoCameraConfigurationTab);
            app.CameraStatusLampLabel.Text = 'Camera Status';
            app.CameraStatusLamp = uilamp(app.LiveVideoCameraConfigurationTab);
            app.ImageSizeLabel = uilabel(app.LiveVideoCameraConfigurationTab);
            app.ImageSizeLabel.Text = 'Image Size: ';
            app.CaptureButton = uibutton(app.LiveVideoCameraConfigurationTab, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.WidthxHeightLabel = uilabel(app.LiveVideoCameraConfigurationTab);
            app.WidthxHeightLabel.Text = 'Width x Height';
            app.DelayBetweenEachFramesmsDropDownLabel = uilabel(app.LiveVideoCameraConfigurationTab);
            app.DelayBetweenEachFramesmsDropDownLabel.Text = 'Delay Between Each Frames (ms)';
            app.DelayBetweenEachFramesmsDropDown = uidropdown(app.LiveVideoCameraConfigurationTab);
            app.DelayBetweenEachFramesmsDropDown.Items = {'1000', '500', '200', '100', '50', '20', '10', '5'};
            app.DelayBetweenEachFramesmsDropDown.ValueChangedFcn = createCallbackFcn(app, @DelayBetweenEachFramesmsDropDownValueChanged, true);
            app.DelayBetweenEachFramesmsDropDown.Value = '1000';
            app.FPSLabel = uilabel(app.LiveVideoCameraConfigurationTab);
            app.FPSLabel.Text = 'FPS';
            app.RGBChannelAnalysisTab = uitab(app.TabGroup);
            app.RGBChannelAnalysisTab.Title = 'RGB Channel Analysis';
            app.UIAxes_BH = uiaxes(app.RGBChannelAnalysisTab);
            title(app.UIAxes_BH, 'Histogram of Blue Channel')
            xlabel(app.UIAxes_BH, 'X')
            ylabel(app.UIAxes_BH, 'Y')
            zlabel(app.UIAxes_BH, 'Z')
            app.UIAxes_GH = uiaxes(app.RGBChannelAnalysisTab);
            title(app.UIAxes_GH, 'Histogram of Green Channel')
            xlabel(app.UIAxes_GH, 'X')
            ylabel(app.UIAxes_GH, 'Y')
            zlabel(app.UIAxes_GH, 'Z')
            app.UIAxes_RH = uiaxes(app.RGBChannelAnalysisTab);
            title(app.UIAxes_RH, 'Histogram of Red Channel')
            xlabel(app.UIAxes_RH, 'X')
            ylabel(app.UIAxes_RH, 'Y')
            zlabel(app.UIAxes_RH, 'Z')
            app.UIAxes_BI = uiaxes(app.RGBChannelAnalysisTab);
            title(app.UIAxes_BI, 'Blue Channel of Image')
            xlabel(app.UIAxes_BI, 'X')
            ylabel(app.UIAxes_BI, 'Y')
            zlabel(app.UIAxes_BI, 'Z')
            app.UIAxes_GI = uiaxes(app.RGBChannelAnalysisTab);
            title(app.UIAxes_GI, 'Green Channel of Image')
            xlabel(app.UIAxes_GI, 'X')
            ylabel(app.UIAxes_GI, 'Y')
            zlabel(app.UIAxes_GI, 'Z')
            app.UIAxes_RI = uiaxes(app.RGBChannelAnalysisTab);
            title(app.UIAxes_RI, 'Red Channel of Image')
            xlabel(app.UIAxes_RI, 'X')
            ylabel(app.UIAxes_RI, 'Y')
            zlabel(app.UIAxes_RI, 'Z')
            app.GenerateRGBChannelDataButton = uibutton(app.RGBChannelAnalysisTab, 'push');
            app.GenerateRGBChannelDataButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateRGBChannelDataButtonPushed, true);
            app.GenerateRGBChannelDataButton.Text = 'Generate RGB Channel Data';
            app.MadebyVenisPrajapatiLabel_2 = uilabel(app.RGBChannelAnalysisTab);
            app.MadebyVenisPrajapatiLabel_2.Text = 'Made by : Venis Prajapati';
            app.ApplyingThresholdTab = uitab(app.TabGroup);
            app.ApplyingThresholdTab.Title = 'Applying Threshold';
            app.UIAxes4 = uiaxes(app.ApplyingThresholdTab);
            title(app.UIAxes4, 'Image after Applying Specific Range of Threshold Values')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.MadebyVenisPrajapatiLabel_3 = uilabel(app.ApplyingThresholdTab);
            app.MadebyVenisPrajapatiLabel_3.Text = 'Made by : Venis Prajapati';
            app.RetrieveImageforProcessingButton = uibutton(app.ApplyingThresholdTab, 'push');
            app.RetrieveImageforProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @RetrieveImageforProcessingButtonPushed, true);
            app.RetrieveImageforProcessingButton.Text = 'Retrieve Image for Processing';
            app.Label = uilabel(app.ApplyingThresholdTab);
            app.Label.Text = 'Enter Sarting and Ending range of RGB to apply specific Threshold Values';
            app.RedColorstartingSpinnerLabel = uilabel(app.ApplyingThresholdTab);
            app.RedColorstartingSpinnerLabel.Text = 'Red Color starting';
            app.RedColorstartingSpinner = uispinner(app.ApplyingThresholdTab);
            app.RedColorstartingSpinner.ValueChangingFcn = createCallbackFcn(app, @RedColorstartingSpinnerValueChanging, true);
            app.RedColorstartingSpinner.ValueChangedFcn = createCallbackFcn(app, @RedColorstartingSpinnerValueChanged, true);
            app.RedColorendingSpinnerLabel = uilabel(app.ApplyingThresholdTab);
            app.RedColorendingSpinnerLabel.Text = 'Red Color ending';
            app.RedColorendingSpinner = uispinner(app.ApplyingThresholdTab);
            app.RedColorendingSpinner.ValueChangingFcn = createCallbackFcn(app, @RedColorendingSpinnerValueChanging, true);
            app.RedColorendingSpinner.ValueChangedFcn = createCallbackFcn(app, @RedColorendingSpinnerValueChanged, true);
            app.GreenColorstartingSpinnerLabel = uilabel(app.ApplyingThresholdTab);
            app.GreenColorstartingSpinnerLabel.Text = 'Green Color starting';
            app.GreenColorstartingSpinner = uispinner(app.ApplyingThresholdTab);
            app.GreenColorstartingSpinner.ValueChangingFcn = createCallbackFcn(app, @GreenColorstartingSpinnerValueChanging, true);
            app.GreenColorstartingSpinner.ValueChangedFcn = createCallbackFcn(app, @GreenColorstartingSpinnerValueChanged, true);
            app.BlueColorstartingSpinnerLabel = uilabel(app.ApplyingThresholdTab);
            app.BlueColorstartingSpinnerLabel.Text = 'Blue Color starting';
            app.BlueColorstartingSpinner = uispinner(app.ApplyingThresholdTab);
            app.BlueColorstartingSpinner.ValueChangingFcn = createCallbackFcn(app, @BlueColorstartingSpinnerValueChanging, true);
            app.BlueColorstartingSpinner.ValueChangedFcn = createCallbackFcn(app, @BlueColorstartingSpinnerValueChanged, true);
            app.GreenColorendingSpinnerLabel = uilabel(app.ApplyingThresholdTab);
            app.GreenColorendingSpinnerLabel.Text = 'Green Color ending';
            app.GreenColorendingSpinner = uispinner(app.ApplyingThresholdTab);
            app.GreenColorendingSpinner.ValueChangingFcn = createCallbackFcn(app, @GreenColorendingSpinnerValueChanging, true);
            app.GreenColorendingSpinner.ValueChangedFcn = createCallbackFcn(app, @GreenColorendingSpinnerValueChanged, true);
            app.BlueColorendingSpinnerLabel = uilabel(app.ApplyingThresholdTab);
            app.BlueColorendingSpinnerLabel.Text = 'Blue Color ending';
            app.BlueColorendingSpinner = uispinner(app.ApplyingThresholdTab);
            app.BlueColorendingSpinner.ValueChangingFcn = createCallbackFcn(app, @BlueColorendingSpinnerValueChanging, true);
            app.BlueColorendingSpinner.ValueChangedFcn = createCallbackFcn(app, @BlueColorendingSpinnerValueChanged, true);
            app.Label_2 = uilabel(app.ApplyingThresholdTab);
            app.Label_2.Text = 'Analysis of Region Of Interest after applying Threshold values';
            app.GenerateAnalysisofROIButton = uibutton(app.ApplyingThresholdTab, 'push');
            app.GenerateAnalysisofROIButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAnalysisofROIButtonPushed, true);
            app.GenerateAnalysisofROIButton.Text = 'Generate Analysis of ROI';
            app.TotalnumberofPixelsinHighlightedRegionLabel = uilabel(app.ApplyingThresholdTab);
            app.TotalnumberofPixelsinHighlightedRegionLabel.Text = 'Total number of Pixels in Highlighted Region:';
            app.Label_3 = uilabel(app.ApplyingThresholdTab);
            app.Label_3.Text = '1,00,000';
            app.AverageIntensityoftheHighlightedRegionLabel = uilabel(app.ApplyingThresholdTab);
            app.AverageIntensityoftheHighlightedRegionLabel.Text = 'Average Intensity of the Highlighted Region:';
            app.RGBLabel = uilabel(app.ApplyingThresholdTab);
            app.RGBLabel.Text = '[R G B]';
            app.TotalnumberofPixelsintheImageLabel = uilabel(app.ApplyingThresholdTab);
            app.TotalnumberofPixelsintheImageLabel.Text = 'Total number of Pixels in the Image:';
            app.Label_4 = uilabel(app.ApplyingThresholdTab);
            app.Label_4.Text = '1,00,000';
            app.PixelAcquisitionTab = uitab(app.TabGroup);
            app.PixelAcquisitionTab.Title = 'Pixel Acquisition';
            app.UIAxes3 = uiaxes(app.PixelAcquisitionTab);
            title(app.UIAxes3, 'Locate Pixel to Aquire It''s RGB Values with respect to time')
            xlabel(app.UIAxes3, 'X Axis')
            ylabel(app.UIAxes3, 'Y Axis')
            zlabel(app.UIAxes3, 'Z')
            app.XAxisSpinnerLabel = uilabel(app.PixelAcquisitionTab);
            app.XAxisSpinnerLabel.Text = 'X Axis';
            app.XAxisSpinner = uispinner(app.PixelAcquisitionTab);
            app.XAxisSpinner.ValueChangingFcn = createCallbackFcn(app, @XAxisSpinnerValueChanging, true);
            app.YAxisSpinnerLabel = uilabel(app.PixelAcquisitionTab);
            app.YAxisSpinnerLabel.Text = 'Y Axis';
            app.YAxisSpinner = uispinner(app.PixelAcquisitionTab);
            app.YAxisSpinner.ValueChangingFcn = createCallbackFcn(app, @YAxisSpinnerValueChanging, true);
            app.Lamp = uilamp(app.PixelAcquisitionTab);
            app.CapturingStatusSwitchLabel = uilabel(app.PixelAcquisitionTab);
            app.CapturingStatusSwitchLabel.Text = 'Capturing Status';
            app.CapturingStatusSwitch = uiswitch(app.PixelAcquisitionTab, 'slider');
            app.CapturingStatusSwitch.ValueChangedFcn = createCallbackFcn(app, @CapturingStatusSwitchValueChanged, true);
            app.PixelAcquiringButton = uibutton(app.PixelAcquisitionTab, 'state');
            app.PixelAcquiringButton.ValueChangedFcn = createCallbackFcn(app, @PixelAcquiringButtonValueChanged, true);
            app.PixelAcquiringButton.Text = 'Pixel Acquiring';
            app.MadebyVenisPrajapatiLabel_4 = uilabel(app.PixelAcquisitionTab);
            app.MadebyVenisPrajapatiLabel_4.Text = 'Made by : Venis Prajapati';
            app.PixelIntensityAcquisitionTab = uitab(app.TabGroup);
            app.PixelIntensityAcquisitionTab.Title = 'Pixel Intensity Acquisition';
            app.UIAxes4_1 = uiaxes(app.PixelIntensityAcquisitionTab);
            title(app.UIAxes4_1, 'Red Color Intensity w.r.t.Time')
            xlabel(app.UIAxes4_1, 'Time')
            ylabel(app.UIAxes4_1, 'Intensity Values ( 0-255 )')
            zlabel(app.UIAxes4_1, 'Z')
            app.UIAxes4_2 = uiaxes(app.PixelIntensityAcquisitionTab);
            title(app.UIAxes4_2, 'Green Color Intensity w.r.t.Time')
            xlabel(app.UIAxes4_2, 'Time')
            ylabel(app.UIAxes4_2, 'Intensity Values ( 0-255 )')
            zlabel(app.UIAxes4_2, 'Z')
            app.UIAxes4_3 = uiaxes(app.PixelIntensityAcquisitionTab);
            title(app.UIAxes4_3, 'Blue Color Intensity w.r.t.Time')
            xlabel(app.UIAxes4_3, 'Time')
            ylabel(app.UIAxes4_3, 'Intensity Values ( 0-255 )')
            zlabel(app.UIAxes4_3, 'Z')
            app.MadebyVenisPrajapatiLabel_5 = uilabel(app.PixelIntensityAcquisitionTab);
            app.MadebyVenisPrajapatiLabel_5.Text = 'Made by : Venis Prajapati';
            app.FPSLabel2 = uilabel(app.PixelIntensityAcquisitionTab);
            app.FPSLabel2.Text = 'FPS';
            app.ExportLiveDataTab = uitab(app.TabGroup);
            app.ExportLiveDataTab.Title = 'Export Live Data';
            app.UITable = uitable(app.ExportLiveDataTab);
            app.UITable.ColumnName = {'Data Index'; 'Time'; 'Red Pixel Intensity'; 'Green Pixel Intensity'; 'Blue Pixel Intensity'};
            app.UITable.RowName = {};
            app.MadebyVenisPrajapatiLabel_6 = uilabel(app.ExportLiveDataTab);
            app.MadebyVenisPrajapatiLabel_6.Text = 'Made by : Venis Prajapati';
            app.DeveloperInformationTab = uitab(app.TabGroup);
            app.DeveloperInformationTab.Title = 'Developer Information';
            app.Image = uiimage(app.DeveloperInformationTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'live_video_analysis_tool_logo.jpg');
            app.VenisVPrajapatiLabel = uilabel(app.DeveloperInformationTab);
            app.VenisVPrajapatiLabel.Text = 'Venis V. Prajapati';
            app.Hyperlink = uihyperlink(app.DeveloperInformationTab);
            app.Hyperlink.URL = 'https://github.com/venisprajapati';
            app.Hyperlink.Text = 'github.com/venisprajapati';
            app.Hyperlink2 = uihyperlink(app.DeveloperInformationTab);
            app.Hyperlink2.URL = 'https://www.linkedin.com/in/venisprajapati/';
            app.Hyperlink2.Text = 'linkedin.com/in/venisprajapati';
            app.Hyperlink3 = uihyperlink(app.DeveloperInformationTab);
            app.Hyperlink3.URL = 'https://github.com/venisprajapati/live-video-analysis-tool';
            app.Hyperlink3.Text = 'Live Video Analysis Tool';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Live_Video_Analysis_Application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end